classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Wholebodyimagingprocessingpluginv010UIFigure  matlab.ui.Figure
        Button             matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        Button_5           matlab.ui.control.Button
        Button_6           matlab.ui.control.Button
        MinEditFieldLabel  matlab.ui.control.Label
        MinEditField       matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        MaxEditFieldLabel  matlab.ui.control.Label
        MaxEditField       matlab.ui.control.NumericEditField
        ColormapLabel      matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        ColormapButton     matlab.ui.control.Button
        Panel_2            matlab.ui.container.Panel
        Button_4           matlab.ui.control.Button
        ROIButton          matlab.ui.control.Button
        Button_7           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Img % Description
        f
        newpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            %oldpath = cd;
            if isempty(app.newpath) || ~exist(app.newpath,'dir')
                app.newpath = cd;
            end
            
            cd(app.newpath);
            [filename,dir,~] = uigetfile({'*.tif;*.jpg;*.png;*.bmp'});
            if filename ~= 0
                app.newpath = dir;
                app.Img = imread([dir,filename]);
                
                app.f=figure;
                imagesc(app.Img,[app.MinEditField.Value,app.MaxEditField.Value]);
                axis('off');
                axis('equal'); 
                colorbar;
                colormap(app.DropDown.Value)
                %cd(oldpath)
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %APPLY
            imagesc(app.Img,[app.MinEditField.Value,app.MaxEditField.Value]);
            axis('off');
            axis('equal'); 
            colorbar;
            colormap(app.DropDown.Value)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            value = app.DropDown.Value;
        
            imagesc(app.Img,[app.MinEditField.Value,app.MaxEditField.Value]);
            axis('off');
            axis('equal'); 
            colorbar;
            colormap(value)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.MinEditField.Value = 0;
            app.MaxEditField.Value = 30000;
            imagesc(app.Img,[app.MinEditField.Value,app.MaxEditField.Value]);
            axis('off');
            axis('equal'); 
            colorbar;
            colormap(app.DropDown.Value)
        end

        % Button pushed function: ColormapButton
        function ColormapButtonPushed(app, event)
           
            colormapeditor
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            imagesc(app.Img,[app.MinEditField.Value,app.MaxEditField.Value]);
            axis('off');
            axis('equal'); 
            colorbar;
            colormap(app.DropDown.Value)
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            
            roi = drawrectangle;
            rect = roi.Position;
            x0 = round(rect(1));
            y0 = round(rect(2));
            xlength = round(rect(3));
            ylength = round(rect(4));
            if y0+ylength < size(app.Img,1) && x0+xlength < size(app.Img,2)
                imagesc(app.Img(y0:y0+ylength,x0:x0+xlength),[app.MinEditField.Value,app.MaxEditField.Value]);
                axis('equal');
                axis('off');
                axis('tight');
                %axis('equal'); 
                colorbar;
                colormap(app.DropDown.Value)
            else
                delete(roi);
            end
            %.Title.String = "ROI";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
            %h = figure;
            %h.Visible = 'off';
            %copyUIAxes(,h)
            %set(gca,'Position',[0,0,0.8,0.8])
            %axis('normal');
            [filename,path] = uiputfile('*.png','Save file name','untitled');
            filepath=strcat(path,filename);
            %saveas(app.f,filepath,'tif');
            %fig = getframe(app.f);
            print(app.f,'-dpng','-r300',filepath)
            
        end

        % Callback function
        function Button_8Pushed(app, event)
            %Batch Mode
%             path = uigetdir;
%             cd(path);
%             
%             dirlist = dir(path);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Wholebodyimagingprocessingpluginv010UIFigure = uifigure('Visible', 'off');
            app.Wholebodyimagingprocessingpluginv010UIFigure.Name = 'Whole-body imaging processing plugin v0.1.0';
            app.Button = uibutton(app.Wholebodyimagingprocessingpluginv010UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入图片';
            app.Panel = uipanel(app.Wholebodyimagingprocessingpluginv010UIFigure);
            app.Panel.Title = '颜色调节';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '应用';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '重置';
            app.MinEditFieldLabel = uilabel(app.Panel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.Panel, 'numeric');
            app.MinEditField.ValueDisplayFormat = '%.0f';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '像素值显示范围';
            app.MaxEditFieldLabel = uilabel(app.Panel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.Panel, 'numeric');
            app.MaxEditField.ValueDisplayFormat = '%.0f';
            app.MaxEditField.Value = 30000;
            app.ColormapLabel = uilabel(app.Panel);
            app.ColormapLabel.Text = '选择合适的Colormap';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'jet', 'turbo', 'hot', 'gray', 'parula', 'summer', 'hsv', 'cool', 'spring', 'autumn', 'winter', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'flag'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'jet';
            app.ColormapButton = uibutton(app.Panel, 'push');
            app.ColormapButton.ButtonPushedFcn = createCallbackFcn(app, @ColormapButtonPushed, true);
            app.ColormapButton.Text = 'Colormap编辑';
            app.Panel_2 = uipanel(app.Wholebodyimagingprocessingpluginv010UIFigure);
            app.Panel_2.Title = '区域选择';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '重置';
            app.ROIButton = uibutton(app.Panel_2, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = '框选ROI';
            app.Button_7 = uibutton(app.Wholebodyimagingprocessingpluginv010UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '保存图片';
            % Show the figure after all components are created
            app.Wholebodyimagingprocessingpluginv010UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Wholebodyimagingprocessingpluginv010UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Wholebodyimagingprocessingpluginv010UIFigure)
        end
    end
end